classdef EasyEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AEditFieldLabel    matlab.ui.control.Label
        EditA              matlab.ui.control.NumericEditField
        BEditFieldLabel    matlab.ui.control.Label
        EditB              matlab.ui.control.NumericEditField
        CEditFieldLabel    matlab.ui.control.Label
        EditC              matlab.ui.control.NumericEditField
        BtnCal             matlab.ui.control.Button
        BtnQuit            matlab.ui.control.Button
        LabelTitle         matlab.ui.control.Label
        CEditFieldLabel_2  matlab.ui.control.Label
        TxTC               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Avalue % Description
        Bvalue
               
    end
    properties (SetObservable)
        Cvalue
        Cstatus
    end
    methods (Access = private)
        
        function CvalueChangedFcn(app,~,~)
            app.EditC.Value = app.Cvalue;
            if mod(app.Cvalue,2)
                app.Cstatus = '奇数';
            else
                app.Cstatus = '偶数';
            end
        end
        
        function CstatusChangedFcn(app,~,~)
            app.TxTC.Value = app.Cstatus;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditA.Value = 0;
            app.EditB.Value = 0;
            app.EditC.Value = 0;
            app.Avalue = 0;
            app.Bvalue = 0;
            app.Cvalue = 0;
            addlistener(app,'Cvalue','PostSet',@app.CvalueChangedFcn)
            addlistener(app,'Cstatus','PostSet',@app.CstatusChangedFcn)
        end

        % Button pushed function: BtnQuit
        function BtnQuitPushed(app, event)
            app.delete();
        end

        % Value changed function: EditA
        function EditAValueChanged(app, event)
            app.Avalue = app.EditA.Value;            
        end

        % Value changed function: EditB
        function EditBValueChanged(app, event)
            app.Bvalue = app.EditB.Value;            
        end

        % Button pushed function: BtnCal
        function BtnCalButtonPushed(app, event)
            app.Cvalue = app.Avalue+app.Bvalue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.EditA = uieditfield(app.UIFigure, 'numeric');
            app.EditA.ValueChangedFcn = createCallbackFcn(app, @EditAValueChanged, true);
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.EditB = uieditfield(app.UIFigure, 'numeric');
            app.EditB.ValueChangedFcn = createCallbackFcn(app, @EditBValueChanged, true);
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.EditC = uieditfield(app.UIFigure, 'numeric');
            app.BtnCal = uibutton(app.UIFigure, 'push');
            app.BtnCal.ButtonPushedFcn = createCallbackFcn(app, @BtnCalButtonPushed, true);
            app.BtnCal.Text = '计    算';
            app.BtnQuit = uibutton(app.UIFigure, 'push');
            app.BtnQuit.ButtonPushedFcn = createCallbackFcn(app, @BtnQuitPushed, true);
            app.BtnQuit.Text = '关    闭';
            app.LabelTitle = uilabel(app.UIFigure);
            app.LabelTitle.Text = '这个例子通过A+B=C，应用事件判断C的奇偶性，并显示出来';
            app.CEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CEditFieldLabel_2.Text = 'C的奇偶性';
            app.TxTC = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyEvent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end